classdef filter_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ad936xFilterWizardUIFigure    matlab.ui.Figure
        Plot                          matlab.ui.control.UIAxes
        ControlsPanel                 matlab.ui.container.Panel
        SaveToWorkspaceButton         matlab.ui.control.Button
        CreateftrFileButton           matlab.ui.control.Button
        CreateNoOSFileButton          matlab.ui.control.Button
        HelpSupportButton             matlab.ui.control.Button
        DeviceSelectionPanel          matlab.ui.container.Panel
        DeviceDropDownLabel           matlab.ui.control.Label
        DeviceDropDown                matlab.ui.control.DropDown
        ProfilesDropDownLabel         matlab.ui.control.Label
        ProfilesDropDown              matlab.ui.control.DropDown
        DataRateMHZEditFieldLabel     matlab.ui.control.Label
        DataRateMHZEditField          matlab.ui.control.NumericEditField
        PLLDivMHZDropDownLabel        matlab.ui.control.Label
        PLLDivMHZDropDown             matlab.ui.control.DropDown
        PLLRateMHZLabel               matlab.ui.control.Label
        DACADCDivMHZDropDown_2Label   matlab.ui.control.Label
        DACADCDivMHZDropDown_2        matlab.ui.control.DropDown
        PLLRateMHZ                    matlab.ui.control.Label
        ADCRateMHZLabel               matlab.ui.control.Label
        DACRateMHZLabel               matlab.ui.control.Label
        ADCRateMHZ                    matlab.ui.control.Label
        DACRateMHZ                    matlab.ui.control.Label
        DESIGNButton                  matlab.ui.control.Button
        DesignTargetButtonGroup       matlab.ui.container.ButtonGroup
        ReceiverButton                matlab.ui.control.ToggleButton
        TransmitterButton             matlab.ui.control.ToggleButton
        FilterOptionsPanel            matlab.ui.container.Panel
        AdvancedCheckBox              matlab.ui.control.CheckBox
        ResponseTypeButtonGroup       matlab.ui.container.ButtonGroup
        LowpassButton                 matlab.ui.control.RadioButton
        RootRaisedCosineButton        matlab.ui.control.RadioButton
        BandpassButton                matlab.ui.control.RadioButton
        EqualizeButton                matlab.ui.control.RadioButton
        PhaseEqualizationCheckBox     matlab.ui.control.CheckBox
        DesignOptionsPanel            matlab.ui.container.Panel
        FrequencySpecificationsPanel  matlab.ui.container.Panel
        PassEditFieldLabel            matlab.ui.control.Label
        PassEditField                 matlab.ui.control.NumericEditField
        StopEditFieldLabel            matlab.ui.control.Label
        StopEditField                 matlab.ui.control.NumericEditField
        AnalogCutoffEditFieldLabel    matlab.ui.control.Label
        AnalogCutoffEditField         matlab.ui.control.NumericEditField
        UnitsDropDownLabel            matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        RFEditFieldLabel              matlab.ui.control.Label
        RFEditField                   matlab.ui.control.NumericEditField
        DecimationRatesPanel          matlab.ui.container.Panel
        FIRDropDownLabel              matlab.ui.control.Label
        FIRDropDown                   matlab.ui.control.DropDown
        UseInternalFIRCheckBox        matlab.ui.control.CheckBox
        HB1DropDownLabel              matlab.ui.control.Label
        HB1DropDown                   matlab.ui.control.DropDown
        HB2DropDownLabel              matlab.ui.control.Label
        HB2DropDown                   matlab.ui.control.DropDown
        HB3DropDownLabel              matlab.ui.control.Label
        HB3DropDown                   matlab.ui.control.DropDown
        FPGAFilterCheckBox            matlab.ui.control.CheckBox
        MagnitudeSpecificationsPanel  matlab.ui.container.Panel
        PassdBEditFieldLabel          matlab.ui.control.Label
        PassdBEditField               matlab.ui.control.NumericEditField
        StopdBEditFieldLabel          matlab.ui.control.Label
        StopdBEditField               matlab.ui.control.NumericEditField
        StopFIRdBEditFieldLabel       matlab.ui.control.Label
        StopFIRdBEditField            matlab.ui.control.NumericEditField
        DeployDevBoardPanel           matlab.ui.container.Panel
        ConnectToTargetButton         matlab.ui.control.Button
        IPAddressEditFieldLabel       matlab.ui.control.Label
        IPAddressEditField            matlab.ui.control.EditField
        ReadClockSettingsButton       matlab.ui.control.Button
        SendFilterToTargetButton      matlab.ui.control.Button
        ADILogo                       matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        DesignResultsLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        designer = ad936xFilterDesigner; % Designer Base Class
        RX % Parameters for RX filter design
        TX % Parameters for TX filter design
        
        filter
        analogfilter
        grpdelayvar
        grpdelaycal
        plotpluto
        
        nfirtaps
    end
    
    methods (Access = private)
        
        function DisplayDefaultImage(app)
            
            max_y = 20;
            max_x = 200;
            ripple = 10;
            Fpass = 90;
            Fcenter = 100;
            Fstop = 110;
            label_colour = 'Red';
            
            cla(app.Plot);
            
            box(app.Plot,'on');
            axis(app.Plot,'on');
            %            axis on;
            
            set(app.Plot,'XTickLabel',{});
            xlabel(app.Plot,'');
            xlim(app.Plot, [0 max_x]);
            text(app.Plot, -10, 0, '0dB');
            
            ylabel(app.Plot,'Mag (dB)');
            set(app.Plot,'YTickLabel',{});
            ylim(app.Plot,[-100 max_y]);
            
            if app.LowpassButton.Value
                mode = 'Lowpass';
            end
            
            switch mode
                case 'Lowpass'
                    diff = 3;
                    % Low part of the low pass
                    line(app.Plot, [0 Fpass], [-ripple -ripple], 'Color', 'Black');
                    line(app.Plot, [0 Fpass], [ripple ripple], 'Color', 'Black');
                    line(app.Plot, [Fpass Fpass], [max_y ripple], 'Color', 'Black');
                    line(app.Plot, [Fpass Fpass], [-ripple -100], 'Color', 'Black');
                    line(app.Plot, [0 Fstop], [0 0], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [Fpass Fstop+30], [-ripple -ripple], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [Fpass Fstop+30], [ripple ripple], 'Color', label_colour, 'LineStyle', ':');
                    
                    %handles_arrows{1} = annotation(app.Plot, 'arrow', 'Y',[max_y ripple], 'X',[130 130]);
                    line(app.Plot, [130 130], [max_y ripple], 'Color',label_colour);
                    line(app.Plot, [130 130], [max_y ripple], 'Color',label_colour);
                    line(app.Plot, [130 130], diff+[ripple, ripple], 'Color', label_colour, 'Marker','v','MarkerSize',10,'MarkerFaceColor',label_colour);
                    
                    %set(handles_arrows, 'Color', label_colour);
                    %handles_arrows{2} = annotation(app.Plot, 'arrow', 'Y',[-max_y -ripple], 'X',[130 130]);
                    %set(handles_arrows{2}, 'Color', label_colour);
                    line(app.Plot, [130 130 ], [-max_y -ripple], 'Color',label_colour);
                    line(app.Plot, [130 130], -diff+[-ripple, -ripple], 'Color', label_colour, 'Marker','^','MarkerSize',10,'MarkerFaceColor',label_colour);
                    text(app.Plot, Fstop + 12, 0, 'A_{pass}', 'BackgroundColor','white', 'EdgeColor','white');
                    
                    % Stop band
                    line(app.Plot, [Fstop max_x-10], [-80 -80], 'Color', 'Black');
                    line(app.Plot, [max_x-10 max_x-10], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fstop Fstop], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fstop Fstop], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fstop Fstop], [-80 -100], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [max_x-10 max_x-10], [-80 -100], 'Color', label_colour, 'LineStyle', ':');
                    
                    line(app.Plot, [150 170], [0 0], 'Color', label_colour, 'LineStyle', ':');
                    text(app.Plot, 0, -108, '0');
                    text(app.Plot, Fpass - 5, -108, 'F_{pass}');
                    text(app.Plot, Fstop - 5, -108, 'F_{stop}');
                    text(app.Plot, max_x - 15, -108, 'Fs_{/2}');
                    
                    % A(stop) label and arrows
                    hTest = text(app.Plot, 150, -40, 'A_{stop}');
                    textExt = get(hTest,'Extent');
                    w = textExt(1) + textExt(3)/2;
                    %                     handles_arrows{3} = annotation(app.Plot, 'arrow', 'Y',[-35 0], 'X',[w w]);
                    line(app.Plot, [w w], [-35 0], 'Color',label_colour);
                    line(app.Plot, [w w], -diff+[0, 0], 'Color', label_colour, 'Marker','^','MarkerSize',10,'MarkerFaceColor',label_colour);
                    
                    %                     set(handles_arrows{3}, 'Color', label_colour);
                    %                     handles_arrows{4} = annotation(app.Plot, 'arrow', 'Y',[-45 -80], 'X',[w w]);
                    %                     set(handles_arrows{4}, 'Color', label_colour);
                    line(app.Plot, [w w], [-45 -80], 'Color',label_colour);
                    line(app.Plot, [w w], diff+[-80, -80], 'Color', label_colour, 'Marker','v','MarkerSize',10,'MarkerFaceColor',label_colour);
                    
                    % reparent arrows within the filter plot so they're displayed properly
                    %                     plot = findall(gcf, 'type', 'axes', 'Tag', 'magnitude_plot');
                    %                     for i = 1:4
                    %                         set(handles_arrows{i}, 'Parent', plot);
                    %                     end
                    
                case 'Root Raised Cosine'
                    % Pass band
                    line(app.Plot, [0 Fpass], [-ripple -ripple], 'Color', 'Black');
                    line(app.Plot, [0 Fpass], [ripple ripple], 'Color', 'Black');
                    line(app.Plot, [Fpass Fpass], [max_y ripple], 'Color', 'Black');
                    line(app.Plot, [Fpass Fpass], [-ripple -100], 'Color', 'Black');
                    
                    % Stop band
                    line(app.Plot, [Fstop max_x-10], [-80 -80], 'Color', 'Black');
                    line(app.Plot, [max_x-10 max_x-10], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fstop Fstop], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fstop Fstop], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fstop Fstop], [-80 -100], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [max_x-10 max_x-10], [-80 -100], 'Color', label_colour, 'LineStyle', ':');
                    
                    text(app.Plot, 0, -108, '0');
                    text(app.Plot, Fpass - 5, -108, 'F_{pass}');
                    text(app.Plot, Fstop - 5, -108, 'F_{stop}');
                    text(app.Plot, max_x - 15, -108, 'Fs_{/2}');
                    
                case 'Bandpass'
                    Fpass = 20;
                    Fcenter = 80;
                    Fstop = 30;
                    % pass part of the bandpass
                    line(app.Plot, [Fcenter-Fpass Fcenter+Fpass], [-ripple -ripple], 'Color', 'Black');
                    line(app.Plot, [Fcenter-Fstop Fcenter+Fstop], [ripple ripple], 'Color', 'Black');
                    line(app.Plot, [Fcenter-Fstop Fcenter-Fstop], [max_y ripple], 'Color', 'Black');
                    line(app.Plot, [Fcenter+Fstop Fcenter+Fstop], [max_y ripple], 'Color', 'Black');
                    line(app.Plot, [Fcenter-Fpass Fcenter-Fpass], [-ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fcenter+Fpass Fcenter+Fpass], [-ripple -80], 'Color', 'Black');
                    
                    line(app.Plot, [0 Fcenter+Fstop], [0 0], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [Fcenter+Fstop Fcenter+Fstop+30], [-ripple -ripple], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [Fcenter+Fstop Fcenter+Fstop+30], [ripple ripple], 'Color', label_colour, 'LineStyle', ':');
                    
                    [x1, y1] = xy2norm(130, ripple, handles);
                    [x2, y2] = xy2norm(130, max_y, handles);
                    handles_arrows{1} = annotation(app.Plot, 'arrow', 'Y',[y2 y1], 'X',[x1 x2]);
                    set(handles_arrows{1}, 'Color', label_colour);
                    [x1, y1] = xy2norm(130, -ripple, handles);
                    [x2, y2] = xy2norm(130, -max_y, handles);
                    handles_arrows{2} = annotation(app.Plot, 'arrow', 'Y',[y2 y1], 'X',[x1 x2]);
                    set(handles_arrows{2}, 'Color', label_colour);
                    text(app.Plot, Fcenter + Fstop + 12, 0, 'A_{pass}', 'BackgroundColor','white', 'EdgeColor','white');
                    
                    % Stop band
                    line(app.Plot, [Fcenter+Fstop max_x-10], [-80 -80], 'Color', 'Black');
                    line(app.Plot, [0 Fcenter-Fstop], [-80 -80], 'Color', 'Black');
                    
                    line(app.Plot, [max_x-10 max_x-10], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fcenter+Fstop Fcenter+Fstop], [-80+ripple -80], 'Color', 'Black');
                    line(app.Plot, [Fcenter-Fstop Fcenter-Fstop], [-80+ripple -80], 'Color', 'Black');
                    
                    line(app.Plot, [Fcenter-Fstop Fcenter-Fstop], [ripple -100], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [Fcenter+Fstop Fcenter+Fstop], [ripple -100], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [max_x-10 max_x-10], [-80 -100], 'Color', label_colour, 'LineStyle', ':');
                    line(app.Plot, [Fcenter Fcenter], [0 -100], 'Color', label_colour, 'LineStyle', ':');
                    %        line(app.Plot, [150 170], [0 0], 'Color', label_colour, 'LineStyle', ':');
                    
                    % Labels "0" "Fcenter"
                    text(app.Plot, 0, -108, '0');
                    text(app.Plot, Fcenter - 5, -108, 'F_{center}');
                    line(app.Plot, [Fcenter Fcenter+Fpass], [-40 -40], 'Color', label_colour, 'LineStyle', ':');
                    text(app.Plot, Fcenter + Fstop +5, -40, 'F_{pass}');
                    line(app.Plot, [Fcenter Fcenter+Fstop], [-60 -60], 'Color', label_colour, 'LineStyle', ':');
                    text(app.Plot, Fcenter + Fstop +5, -60, 'F_{stop}');
                    text(app.Plot, max_x - 15, -108, 'Fs_{/2}');
                    
                    % A(stop) label and arrows
                    hTest = text(app.Plot, Fcenter/4, -40, 'A_{stop}');
                    textExt = get(hTest,'Extent');
                    w = textext(app.Plot, 1) + textext(app.Plot, 3)/2;
                    [x1, y1] = xy2norm(w, 0, handles);
                    [x2, y2] = xy2norm(w, -35, handles);
                    handles_arrows{3} = annotation(app.Plot, 'arrow', 'Y',[y2 y1], 'X',[x1 x2]);
                    set(handles_arrows{3}, 'Color', label_colour);
                    [x1, y1] = xy2norm(w, -80, handles);
                    [x2, y2] = xy2norm(w, -45, handles);
                    handles_arrows{4} = annotation(app.Plot, 'arrow', 'Y',[y2 y1], 'X',[x1 x2]);
                    set(handles_arrows{4}, 'Color', label_colour);
                    
                case 'Equalize'
                    line(app.Plot, [0 max_x-10], [0 0], 'Color', 'Black');
                    line(app.Plot, [max_x-10 max_x-10], [0 ripple], 'Color', 'Black');
                    text(app.Plot, 0, -108, '0');
                    text(app.Plot, max_x - 15, -108, 'Fs_{/2}');
            end
            
            
        end
        
        
        function DoDesign(app)
            
            if app.ReceiverButton.Value
                sel = app.RX;
            else
                sel = app.TX;
            end
            filter_type = app.ReceiverButton.Value;
            
            %set(handles.design_filter, 'Enable', 'off');
            
            %sel = get_current_rxtx(handles);
            
            converter_rate = sel.Rdata * sel.FIR * sel.HB1 * sel.HB2 * sel.HB3;
            PLL_rate = sel.PLL_rate * 1e6;
            FIR_rate = sel.Rdata * sel.FIR;
            HB1_rate = FIR_rate * sel.HB1;
            HB2_rate = HB1_rate * sel.HB2;
            HB3_rate = HB2_rate * sel.HB3;
            
            % determine the RF bandwidth from the current caldiv
            RFbw = sel.RFbw;
            
            % filter design input structure
            filter_input = struct(sel);
            filter_input.converter_rate = converter_rate;
            filter_input.PLL_rate = PLL_rate;
            filter_input.dBstop_FIR = sel.FIRdBmin;
            filter_input.wnom = sel.wnom;
            filter_input.int_FIR = sel.FIR;
            %filter_input.FPGAfilter = get(handles.use_FPGAfilter, 'Value');
            filter_input.RFbw = RFbw;
            
            %plot_buttons_off(handles);
            
            % make sure things are sane before drawing
%             if sel.Fpass >= sel.Fstop || sel.Fpass <= 0 || sel.Fstop <= 0
%                 display_default_image(hObject);
%                 plot_buttons_off(handles);
%                 handles.active_plot = 0;
%                 set(handles.design_filter, 'Enable', 'on');
%                 guidata(hObject, handles);
%                 return;
%             end
            
%             oldpointer = get(gcf, 'pointer');
%             set(gcf,'Pointer','watch');
%             drawnow;
%             
%             if (filter_input.phEQ == 0)
%                 filter_input.phEQ = minimize_group_delay(handles, filter_input);
%             end
            
            %% Call designer
            filter_result = internal_design_filter(filter_input);
            
            app.filter = filter_result.filter;
            app.analogfilter = filter_result.Hanalog;
            app.grpdelayvar = filter_result.grpdelayvar;
            app.grpdelaycal = clone(filter_result.filter);
            app.plotpluto = clone(filter_result.filter);
            
            % if filter_type == 1  % Rx
            %     addStage(handles.grpdelaycal,filter_result.Hd2,1);
            %     addStage(handles.grpdelaycal,filter_result.Hd1,2);
            %     handles.plutofilter = clone(handles.grpdelaycal);
            %     ast = 80;
            %     tw = 0.08;
            %     f = fdesign.decimator(8, 'Nyquist', 8, 'TW,Ast', tw, ast);
            %     hf = design(f,'SystemObject',true);
            %     hf.FullPrecisionOverride = false;
            %     hf.OutputDataType='Custom';
            %     hf.CustomOutputDataType=numerictype([],16,15);
            %     hf.CoefficientsDataType='Custom';
            %     hf.CustomCoefficientsDataType=numerictype([],16,15);
            %     hf.ProductDataType='Custom';
            %     hf.CustomProductDataType=numerictype([],16,15);
            %     hf.AccumulatoRdataType = 'Custom';
            %     hf.CustomAccumulatoRdataType=numerictype([],16,15);
            %
            %     addStage(handles.plutofilter, hf);
            %     addStage(handles.plotpluto, hf);
            %     handles.rfirtaps = int32(filter_result.firtaps);
            %
            %     % values used for saving to a filter file or pushing to the target directly
            %     handles.rx = filter_result;
            %
            % else  % Tx
            %     addStage(handles.grpdelaycal,filter_result.Hd1);
            %     addStage(handles.grpdelaycal,filter_result.Hd2);
            %     handles.plutofilter = clone(handles.grpdelaycal);
            %     ast = 80;
            %     tw = 0.08;
            %     f = fdesign.interpolator(8, 'Nyquist', 8, 'TW,Ast', tw, ast);
            %     hf = design(f,'kaiserwin','SystemObject',true);
            %     hf.Numerator = hf.Numerator./8;
            %     hf.FullPrecisionOverride = false;
            %     hf.OutputDataType='Custom';
            %     hf.CustomOutputDataType=numerictype([],16,15);
            %     hf.CoefficientsDataType='Custom';
            %     hf.CustomCoefficientsDataType=numerictype([],16,15);
            %     hf.ProductDataType='Custom';
            %     hf.CustomProductDataType=numerictype([],16,15);
            %     hf.AccumulatoRdataType = 'Custom';
            %     hf.CustomAccumulatoRdataType=numerictype([],16,15);
            %
            %     addStage(handles.plutofilter, hf, 1);
            %     addStage(handles.plotpluto, hf, 1);
            %     handles.tfirtaps = int32(filter_result.firtaps);
            %
            %     % values used for saving to a filter file or pushing to the target directly
            %     handles.tx = filter_result;
            % end
            
            % set(gcf, 'Pointer', oldpointer);
            
            % if get(handles.phase_eq, 'Value')
            %     set(handles.target_delay, 'String', num2str(filter_input.phEQ, 8));
            % end
            
            app.nfirtaps = filter_result.nfirtaps;
            % handles.Hmiddle = filter_result.Hmiddle;
            
            % set(handles.FVTool_deeper, 'Visible', 'on');
            % set(handles.FVTool_datarate, 'Visible', 'on');
            
            % set(handles.results_Apass, 'Visible', 'on');
            % set(handles.results_Astop, 'Visible', 'on');
            % set(handles.results_maxinput, 'Visible', 'on');
            % set(handles.results_maxinputdB, 'Visible', 'on');
            % set(handles.results_taps, 'Visible', 'on');
            % set(handles.results_group_delay, 'Visible', 'on');
            %
            % set(handles.results_taps, 'String', [num2str(handles.nfirtaps) ' ']);
            % set(handles.RFbw, 'String', num2str(Hz2value(handles, handles.freq_units, RFbw)));
            
            G = 8192;
            %axes(app.Plot);
            %cla(app.Plot);
            
            if filter_type == 1
                channel = 'Rx';
            else
                channel = 'Tx';
            end
            
            % if get(handles.use_FPGAfilter, 'Value')== 1
            %     plot(app.Plot, linspace(0,sel.Rdata/2,G),mag2db(...
            %         abs(analogresp(channel,linspace(0,sel.Rdata/2,G),converter_rate,filter_result.b1,filter_result.a1,filter_result.b2,filter_result.a2).*freqz(...
            %         handles.plotpluto,linspace(0,sel.Rdata/2,G),converter_rate))));
            %     xlim([0 sel.Rdata/8]);
            % else
            plot(app.Plot, linspace(0,sel.Rdata/2,G),mag2db(...
                abs(analogresp(channel,linspace(0,sel.Rdata/2,G),converter_rate,filter_result.b1,filter_result.a1,filter_result.b2,filter_result.a2).*freqz(...
                filter_result.filter,linspace(0,sel.Rdata/2,G),converter_rate))));
            xlim(app.Plot,[0 sel.Rdata/2]);
            % end
            
            ylim(app.Plot,[-100 10]);
            %%%%%
            %zoom_axis(gca);
            A = get(app.Plot, 'XTick') / 1e6;
            arrayfun(@num2str, A, 'unif', 0);
            set(app.Plot, 'XTickLabel', A);
            %%%%%
            xlabel(app.Plot,'Frequency (MHz)');
            ylabel(app.Plot,'Magnitude (dB)');
            
            % % plot the mask that we are interested in
            % if get(handles.use_FPGAfilter, 'Value')== 1
            %     sel.Fpass = sel.Rdata/2*0.085;
            %     sel.Fstop = sel.Rdata/2*0.165;
            % end
            line(app.Plot,[sel.Fpass sel.Fpass], [-(sel.Apass/2) -100], 'Color', 'Red');
            line(app.Plot,[0 sel.Fpass], [-(sel.Apass/2) -(sel.Apass/2)], 'Color', 'Red');
            line(app.Plot,[0 sel.Fstop], [sel.Apass/2 sel.Apass/2], 'Color', 'Red');
            line(app.Plot,[sel.Fstop sel.Fstop], [sel.Apass/2 -sel.Astop], 'Color', 'Red');
            line(app.Plot,[sel.Fstop sel.Rdata], [-sel.Astop -sel.Astop], 'Color', 'Red');
            
            % % add the quantitative values about actual passband, stopband, and group delay
            % if get(handles.use_FPGAfilter, 'Value')== 1
            %     if get(handles.filter_type, 'Value') == 1
            %         rg_stop = abs(freqz(handles.plotpluto,linspace(sel.Fstop,sel.Rdata/8,G),converter_rate).*analogresp('Rx',linspace(sel.Fstop,sel.Rdata/8,G),converter_rate,filter_result.b1,filter_result.a1,filter_result.b2,filter_result.a2));
            %     else
            %         rg_stop = abs(freqz(handles.plotpluto,linspace(sel.Fstop,sel.Rdata/8,G),converter_rate).*analogresp('Tx',linspace(sel.Fstop,sel.Rdata/8,G),converter_rate,filter_result.b1,filter_result.a1,filter_result.b2,filter_result.a2));
            %     end
            %     Astop_actual = -mag2db(max(rg_stop));
            %     set(handles.results_Astop, 'String', [num2str(Astop_actual) ' dB ']);
            % else
            %set(handles.results_Astop, 'String', [num2str(filter_result.Astop_actual) ' dB ']);
            %end
            % set(handles.results_Apass, 'String', [num2str(filter_result.Apass_actual) ' dB ']);
            % set(handles.results_maxinput, 'String', [num2str(1.0 / max(abs(freqz(filter_result.Hmd)))) ' FS ']);
            % set(handles.results_maxinputdB, 'String', [num2str(20 * log10(1.0 / max(abs(freqz(filter_result.Hmd))))) ' dB ']);
            % set(handles.results_group_delay, 'String', [num2str(filter_result.grpdelayvar * 1e9, 3) ' ns ']);
            %
            % if ~isempty(ver('fixedpoint')) && license('test','fixed_point_toolbox') && license('checkout','fixed_point_toolbox')
            %     set(handles.results_fixed, 'String', 'Fixed Point');
            % else
            %     set(handles.results_fixed, 'String', 'Floating point approx');
            % end
            
            % check if the filter is compatible with available clock rates
            clk_divs = [1 2 3 4 6 8 12 16 24 32 48];
            clk_rates = converter_rate ./ clk_divs;
            % if ~any(4 * sel.Rdata == clk_rates)
            %     warndlg('This filter is not compatible with 2Rx2Tx mode.', 'Filter compatibility');
            % end
            % if ~any(2 * sel.Rdata == clk_rates)
            %     warndlg('This filter is not compatible with 1Rx1Tx mode.', 'Filter compatibility');
            % end
            
            %set(handles.design_filter, 'Visible', 'on');
            %guidata(hObject, handles);
        end
        
        % Setup table with default values
        function BuildTable(app)
            tbl = {'Filter Type',app.RX.Type;...
                'Group Delay',app.RX.GroupDelay;...
                'Variance',app.RX.GroupDelayVariance;
                'FIR Taps',app.RX.NumTaps;...
                'Apass (dB)',app.RX.Apass;...
                'Astop (dB)',app.RX.Astop;...
                'Max Input (FS)',app.RX.MaxInput};
%             app.UITable.RowName = {tbl{:,1}};
            app.UITable.Data = tbl;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RX = ad936xFilterDesigner;%cook_input(struct('Rdata',30e6,'RxTx','Rx'));
            app.TX = ad936xFilterDesigner;%cook_input(struct('Rdata',30e6,'RxTx','Tx'));
            app.BuildTable();
        end

        % Button pushed function: DESIGNButton
        function DESIGNButtonPushed(app, event)
            
            % Draw
            DisplayDefaultImage(app);
            
        end

        % Value changed function: DataRateMHZEditField
        function DataRateMHZEditFieldValueChanged(app, event)
            val = app.DataRateMHZEditField.Value*1e6;
            %app.designer.Rdata = app.DataRateMHZEditField.Value;
            if app.ReceiverButton.Value
                app.RX = cook_input(struct('Rdata',val,'RxTx','Rx'));
            else
                app.TX = cook_input(struct('Rdata',val,'RxTx','Tx'));
            end
        end

        % Button pushed function: SaveToWorkspaceButton
        function SaveToWorkspaceButtonPushed(app, event)
            app.DoDesign();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ad936xFilterWizardUIFigure = uifigure;
            app.ad936xFilterWizardUIFigure.Name = 'ad936x Filter Wizard';
            app.Plot = uiaxes(app.ad936xFilterWizardUIFigure);
            title(app.Plot, 'Filter Response')
            ylabel(app.Plot, 'Magnitude (dB)')
            app.ControlsPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.SaveToWorkspaceButton = uibutton(app.ControlsPanel, 'push');
            app.SaveToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToWorkspaceButtonPushed, true);
            app.SaveToWorkspaceButton.Text = 'Save To Workspace';
            app.CreateftrFileButton = uibutton(app.ControlsPanel, 'push');
            app.CreateftrFileButton.Text = 'Create .ftr File';
            app.CreateNoOSFileButton = uibutton(app.ControlsPanel, 'push');
            app.CreateNoOSFileButton.Text = 'Create No-OS File';
            app.HelpSupportButton = uibutton(app.ControlsPanel, 'push');
            app.HelpSupportButton.Text = 'Help / Support';
            app.DeviceSelectionPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.DeviceSelectionPanel.Title = 'Device Selection';
            app.DeviceDropDownLabel = uilabel(app.DeviceSelectionPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.DeviceSelectionPanel);
            app.DeviceDropDown.Items = {'AD9361', 'AD9364', 'Pluto'};
            app.DeviceDropDown.Value = 'AD9361';
            app.ProfilesDropDownLabel = uilabel(app.DeviceSelectionPanel);
            app.ProfilesDropDownLabel.Text = 'Profiles';
            app.ProfilesDropDown = uidropdown(app.DeviceSelectionPanel);
            app.ProfilesDropDown.Items = {'LTE5 (Rx & Tx)', 'LTE10 (Rx & Tx)', 'LTE15 (Rx & Tx)', 'LTE20 (Rx & Tx)'};
            app.ProfilesDropDown.Value = 'LTE5 (Rx & Tx)';
            app.DataRateMHZEditFieldLabel = uilabel(app.DeviceSelectionPanel);
            app.DataRateMHZEditFieldLabel.Text = 'Data Rate (MHZ)';
            app.DataRateMHZEditField = uieditfield(app.DeviceSelectionPanel, 'numeric');
            app.DataRateMHZEditField.ValueChangedFcn = createCallbackFcn(app, @DataRateMHZEditFieldValueChanged, true);
            app.DataRateMHZEditField.Limits = [0.1 61.44];
            app.DataRateMHZEditField.Value = 30.72;
            app.PLLDivMHZDropDownLabel = uilabel(app.DeviceSelectionPanel);
            app.PLLDivMHZDropDownLabel.Text = 'PLL Div  (MHZ)';
            app.PLLDivMHZDropDown = uidropdown(app.DeviceSelectionPanel);
            app.PLLDivMHZDropDown.Items = {'1x', '2x', '4x', '8x', '16x', '32x', '64x'};
            app.PLLDivMHZDropDown.Value = '1x';
            app.PLLRateMHZLabel = uilabel(app.DeviceSelectionPanel);
            app.PLLRateMHZLabel.Text = 'PLL Rate (MHZ)';
            app.DACADCDivMHZDropDown_2Label = uilabel(app.DeviceSelectionPanel);
            app.DACADCDivMHZDropDown_2Label.Text = 'DAC/ADC Div  (MHZ)';
            app.DACADCDivMHZDropDown_2 = uidropdown(app.DeviceSelectionPanel);
            app.DACADCDivMHZDropDown_2.Items = {'1x', '2x'};
            app.DACADCDivMHZDropDown_2.Value = '1x';
            app.PLLRateMHZ = uilabel(app.DeviceSelectionPanel);
            app.PLLRateMHZ.Text = '0';
            app.ADCRateMHZLabel = uilabel(app.DeviceSelectionPanel);
            app.ADCRateMHZLabel.Text = 'ADC Rate (MHZ)';
            app.DACRateMHZLabel = uilabel(app.DeviceSelectionPanel);
            app.DACRateMHZLabel.Text = 'DAC Rate (MHZ)';
            app.ADCRateMHZ = uilabel(app.DeviceSelectionPanel);
            app.ADCRateMHZ.Text = '0';
            app.DACRateMHZ = uilabel(app.DeviceSelectionPanel);
            app.DACRateMHZ.Text = '0';
            app.DESIGNButton = uibutton(app.ad936xFilterWizardUIFigure, 'push');
            app.DESIGNButton.ButtonPushedFcn = createCallbackFcn(app, @DESIGNButtonPushed, true);
            app.DESIGNButton.Text = 'DESIGN';
            app.DesignTargetButtonGroup = uibuttongroup(app.ad936xFilterWizardUIFigure);
            app.DesignTargetButtonGroup.Title = 'Design Target';
            app.ReceiverButton = uitogglebutton(app.DesignTargetButtonGroup);
            app.ReceiverButton.Text = 'Receiver';
            app.ReceiverButton.Value = true;
            app.TransmitterButton = uitogglebutton(app.DesignTargetButtonGroup);
            app.TransmitterButton.Text = 'Transmitter';
            app.FilterOptionsPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.FilterOptionsPanel.Title = 'Filter Options';
            app.AdvancedCheckBox = uicheckbox(app.FilterOptionsPanel);
            app.AdvancedCheckBox.Text = 'Advanced';
            app.ResponseTypeButtonGroup = uibuttongroup(app.FilterOptionsPanel);
            app.ResponseTypeButtonGroup.Title = 'Response Type';
            app.LowpassButton = uiradiobutton(app.ResponseTypeButtonGroup);
            app.LowpassButton.Text = 'Lowpass';
            app.LowpassButton.Value = true;
            app.RootRaisedCosineButton = uiradiobutton(app.ResponseTypeButtonGroup);
            app.RootRaisedCosineButton.Enable = 'off';
            app.RootRaisedCosineButton.Text = 'Root Raised Cosine';
            app.BandpassButton = uiradiobutton(app.ResponseTypeButtonGroup);
            app.BandpassButton.Enable = 'off';
            app.BandpassButton.Text = 'Bandpass';
            app.EqualizeButton = uiradiobutton(app.ResponseTypeButtonGroup);
            app.EqualizeButton.Enable = 'off';
            app.EqualizeButton.Text = 'Equalize';
            app.PhaseEqualizationCheckBox = uicheckbox(app.FilterOptionsPanel);
            app.PhaseEqualizationCheckBox.Text = 'Phase Equalization';
            app.DesignOptionsPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.DesignOptionsPanel.Title = 'Design Options';
            app.FrequencySpecificationsPanel = uipanel(app.DesignOptionsPanel);
            app.FrequencySpecificationsPanel.Title = 'Frequency Specifications';
            app.PassEditFieldLabel = uilabel(app.FrequencySpecificationsPanel);
            app.PassEditFieldLabel.Text = 'Pass';
            app.PassEditField = uieditfield(app.FrequencySpecificationsPanel, 'numeric');
            app.StopEditFieldLabel = uilabel(app.FrequencySpecificationsPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.FrequencySpecificationsPanel, 'numeric');
            app.AnalogCutoffEditFieldLabel = uilabel(app.FrequencySpecificationsPanel);
            app.AnalogCutoffEditFieldLabel.Text = 'Analog Cutoff';
            app.AnalogCutoffEditField = uieditfield(app.FrequencySpecificationsPanel, 'numeric');
            app.AnalogCutoffEditField.Editable = 'off';
            app.UnitsDropDownLabel = uilabel(app.FrequencySpecificationsPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.FrequencySpecificationsPanel);
            app.UnitsDropDown.Items = {'HZ', 'kHZ', 'MHZ'};
            app.UnitsDropDown.Value = 'HZ';
            app.RFEditFieldLabel = uilabel(app.FrequencySpecificationsPanel);
            app.RFEditFieldLabel.Text = 'RF';
            app.RFEditField = uieditfield(app.FrequencySpecificationsPanel, 'numeric');
            app.RFEditField.Editable = 'off';
            app.DecimationRatesPanel = uipanel(app.DesignOptionsPanel);
            app.DecimationRatesPanel.Title = 'Decimation Rates';
            app.FIRDropDownLabel = uilabel(app.DecimationRatesPanel);
            app.FIRDropDownLabel.Text = 'FIR';
            app.FIRDropDown = uidropdown(app.DecimationRatesPanel);
            app.FIRDropDown.Items = {'1x', '2x', '4x'};
            app.FIRDropDown.Value = '1x';
            app.UseInternalFIRCheckBox = uicheckbox(app.DecimationRatesPanel);
            app.UseInternalFIRCheckBox.Text = 'Use Internal FIR';
            app.HB1DropDownLabel = uilabel(app.DecimationRatesPanel);
            app.HB1DropDownLabel.Text = 'HB1';
            app.HB1DropDown = uidropdown(app.DecimationRatesPanel);
            app.HB1DropDown.Items = {'1x', '2x'};
            app.HB1DropDown.Value = '1x';
            app.HB2DropDownLabel = uilabel(app.DecimationRatesPanel);
            app.HB2DropDownLabel.Text = 'HB2';
            app.HB2DropDown = uidropdown(app.DecimationRatesPanel);
            app.HB2DropDown.Items = {'1x', '2x'};
            app.HB2DropDown.Value = '1x';
            app.HB3DropDownLabel = uilabel(app.DecimationRatesPanel);
            app.HB3DropDownLabel.Text = 'HB3';
            app.HB3DropDown = uidropdown(app.DecimationRatesPanel);
            app.HB3DropDown.Items = {'1x', '2x', '3x'};
            app.HB3DropDown.Value = '1x';
            app.FPGAFilterCheckBox = uicheckbox(app.DecimationRatesPanel);
            app.FPGAFilterCheckBox.Text = 'FPGA Filter';
            app.MagnitudeSpecificationsPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.MagnitudeSpecificationsPanel.Title = 'Magnitude Specifications';
            app.PassdBEditFieldLabel = uilabel(app.MagnitudeSpecificationsPanel);
            app.PassdBEditFieldLabel.Text = 'Pass (dB)';
            app.PassdBEditField = uieditfield(app.MagnitudeSpecificationsPanel, 'numeric');
            app.StopdBEditFieldLabel = uilabel(app.MagnitudeSpecificationsPanel);
            app.StopdBEditFieldLabel.Text = 'Stop (dB)';
            app.StopdBEditField = uieditfield(app.MagnitudeSpecificationsPanel, 'numeric');
            app.StopFIRdBEditFieldLabel = uilabel(app.MagnitudeSpecificationsPanel);
            app.StopFIRdBEditFieldLabel.Text = 'Stop FIR (dB)';
            app.StopFIRdBEditField = uieditfield(app.MagnitudeSpecificationsPanel, 'numeric');
            app.StopFIRdBEditField.Editable = 'off';
            app.DeployDevBoardPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.DeployDevBoardPanel.Title = 'Deploy (Dev Board)';
            app.ConnectToTargetButton = uibutton(app.DeployDevBoardPanel, 'push');
            app.ConnectToTargetButton.Text = 'Connect To Target';
            app.IPAddressEditFieldLabel = uilabel(app.DeployDevBoardPanel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.DeployDevBoardPanel, 'text');
            app.ReadClockSettingsButton = uibutton(app.DeployDevBoardPanel, 'push');
            app.ReadClockSettingsButton.Text = 'Read Clock Settings';
            app.SendFilterToTargetButton = uibutton(app.DeployDevBoardPanel, 'push');
            app.SendFilterToTargetButton.Text = 'Send Filter To Target';
            app.ADILogo = uibutton(app.DeployDevBoardPanel, 'push');
            app.ADILogo.Icon = 'Analog_Devices_Logo.png';
            app.ADILogo.Text = '';
            app.UITable = uitable(app.ad936xFilterWizardUIFigure);
            app.UITable.ColumnName = {'Field'; 'Result'};
            app.UITable.RowName = {};
            app.DesignResultsLabel = uilabel(app.ad936xFilterWizardUIFigure);
            app.DesignResultsLabel.Text = 'Design Results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = filter_designer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ad936xFilterWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ad936xFilterWizardUIFigure)
        end
    end
end