classdef DesignerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ad936xFilterWizardUIFigure     matlab.ui.Figure
        TXPathPanel                    matlab.ui.container.Panel
        HB1DropDownLabel               matlab.ui.control.Label
        TxHB1                          matlab.ui.control.DropDown
        HB2DropDownLabel               matlab.ui.control.Label
        TxHB2                          matlab.ui.control.DropDown
        HB3DropDownLabel               matlab.ui.control.Label
        TxHB3                          matlab.ui.control.DropDown
        FIRDropDownLabel               matlab.ui.control.Label
        TxFIR                          matlab.ui.control.DropDown
        TxRateFIR                      matlab.ui.control.NumericEditField
        TxRateHB1                      matlab.ui.control.NumericEditField
        TxRateHB2                      matlab.ui.control.NumericEditField
        TxRateHB3                      matlab.ui.control.NumericEditField
        RXPathPanel                    matlab.ui.container.Panel
        HB1DropDown_2Label             matlab.ui.control.Label
        RxHB1                          matlab.ui.control.DropDown
        HB2DropDown_2Label             matlab.ui.control.Label
        RxHB2                          matlab.ui.control.DropDown
        HB3DropDown_2Label             matlab.ui.control.Label
        RxHB3                          matlab.ui.control.DropDown
        FIRDropDown_2Label             matlab.ui.control.Label
        RxFIR                          matlab.ui.control.DropDown
        RxRateFIR                      matlab.ui.control.NumericEditField
        RxRateHB1                      matlab.ui.control.NumericEditField
        RxRateHB2                      matlab.ui.control.NumericEditField
        RxRateHB3                      matlab.ui.control.NumericEditField
        ClocksPanel                    matlab.ui.container.Panel
        DACDividerDropDownLabel        matlab.ui.control.Label
        DACDivider                     matlab.ui.control.DropDown
        PLLDividerDropDownLabel        matlab.ui.control.Label
        PLLDivider                     matlab.ui.control.DropDown
        StatusLight                    matlab.ui.control.Lamp
        ErrorStatus                    matlab.ui.control.Label
        MHzLabel_13                    matlab.ui.control.Label
        DACRateEditField_2Label        matlab.ui.control.Label
        DACRate                        matlab.ui.control.NumericEditField
        MHzLabel_14                    matlab.ui.control.Label
        ADCRateEditFieldLabel          matlab.ui.control.Label
        ADCRate                        matlab.ui.control.NumericEditField
        MHzLabel_15                    matlab.ui.control.Label
        PLLRateEditFieldLabel          matlab.ui.control.Label
        PLLRate                        matlab.ui.control.NumericEditField
        AvailableFIRTapsLabel          matlab.ui.control.Label
        AvailableFIRTaps               matlab.ui.control.NumericEditField
        DeviceSettingsPanel            matlab.ui.container.Panel
        DataRateEditFieldLabel         matlab.ui.control.Label
        DataRate                       matlab.ui.control.NumericEditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
        ProfileDropDownLabel           matlab.ui.control.Label
        ProfileDropDown                matlab.ui.control.DropDown
        AutoConfigureHBFIRButton       matlab.ui.control.Button
        MHzLabel_21                    matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        TXFilterTab                    matlab.ui.container.Tab
        UIAxesTX                       matlab.ui.control.UIAxes
        FilterSpecificationsPanel      matlab.ui.container.Panel
        FrequencyPanel                 matlab.ui.container.Panel
        FpassEditFieldLabel            matlab.ui.control.Label
        TxFpass                        matlab.ui.control.NumericEditField
        FstopEditFieldLabel            matlab.ui.control.Label
        TxFstop                        matlab.ui.control.NumericEditField
        AnalogFcutoffEditFieldLabel    matlab.ui.control.Label
        TxAnalogCuttoffFrequency       matlab.ui.control.NumericEditField
        MHzLabel                       matlab.ui.control.Label
        MHzLabel_3                     matlab.ui.control.Label
        MHzLabel_2                     matlab.ui.control.Label
        MagnitudePanel                 matlab.ui.container.Panel
        ApassEditFieldLabel            matlab.ui.control.Label
        TxApass                        matlab.ui.control.NumericEditField
        dBLabel                        matlab.ui.control.Label
        dBLabel_2                      matlab.ui.control.Label
        AstopEditFieldLabel            matlab.ui.control.Label
        TxAstop                        matlab.ui.control.NumericEditField
        AdvancedPanel                  matlab.ui.container.Panel
        TargetDelayEditFieldLabel      matlab.ui.control.Label
        TxphEQ                         matlab.ui.control.NumericEditField
        EnTxphEQ                       matlab.ui.control.CheckBox
        nsLabel                        matlab.ui.control.Label
        DesignResultsTX                matlab.ui.control.Table
        DesignTXFilterButton           matlab.ui.control.Button
        ExportDesignPanelTX            matlab.ui.container.Panel
        ToWorkspaceButton_2            matlab.ui.control.Button
        ToftrFileButton_2              matlab.ui.control.Button
        ToNoOSFileButton_2             matlab.ui.control.Button
        ProgramBoardButton_2           matlab.ui.control.Button
        IPAddressEditField_2Label      matlab.ui.control.Label
        IPAddressEditField_2           matlab.ui.control.EditField
        RXFilterTab                    matlab.ui.container.Tab
        UIAxesRX                       matlab.ui.control.UIAxes
        FilterSpecificationsPanel_2    matlab.ui.container.Panel
        FrequencyPanel_2               matlab.ui.container.Panel
        FpassEditField_2Label          matlab.ui.control.Label
        RxFpass                        matlab.ui.control.NumericEditField
        FstopEditField_2Label          matlab.ui.control.Label
        RxFstop                        matlab.ui.control.NumericEditField
        AnalogFcutoffEditField_2Label  matlab.ui.control.Label
        RxAnalogCuttoffFrequency       matlab.ui.control.NumericEditField
        MHzLabel_18                    matlab.ui.control.Label
        MHzLabel_19                    matlab.ui.control.Label
        MHzLabel_20                    matlab.ui.control.Label
        MagnitudePanel_2               matlab.ui.container.Panel
        ApassEditField_2Label          matlab.ui.control.Label
        RxApass                        matlab.ui.control.NumericEditField
        dBLabel_3                      matlab.ui.control.Label
        dBLabel_4                      matlab.ui.control.Label
        AstopEditField_2Label          matlab.ui.control.Label
        RxAstop                        matlab.ui.control.NumericEditField
        AdvancedPanel_2                matlab.ui.container.Panel
        TargetDelayEditField_2Label    matlab.ui.control.Label
        TargetDelayEditField_2         matlab.ui.control.NumericEditField
        PhaseEqualizeCheckBox_2        matlab.ui.control.CheckBox
        nsLabel_2                      matlab.ui.control.Label
        DesignResultsRX                matlab.ui.control.Table
        DesignRXFilterButton           matlab.ui.control.Button
        ExportDesignPanelRX            matlab.ui.container.Panel
        ToWorkspaceButton              matlab.ui.control.Button
        ToftrFileButton                matlab.ui.control.Button
        ToNoOSFileButton               matlab.ui.control.Button
        ProgramBoardButton             matlab.ui.control.Button
        IPAddressEditFieldLabel        matlab.ui.control.Label
        IPAddressEditField             matlab.ui.control.EditField
        MHzLabel_16                    matlab.ui.control.Label
        MHzLabel_17                    matlab.ui.control.Label
        Button                         matlab.ui.control.Button
    end


    properties (Access = private)
        FilterDesigner % Description
        Black = [0,0,0];
        Red = [1,0,0];
        Green = [0,1,0];
        Grey = [0.65,0.65,0.65];
    end

    methods (Access = private)
    
        function [pass,E] = updateFilterDesigner(app,parameterName)
            E = [];
            try
                needConv = {'DataRate','TxFpass','TxFstop','RxFpass','RxFstop','TxAnalogCuttoffFrequency'};
                if isa(app.(parameterName).Value,'char')
                    v = str2double(app.(parameterName).Value);
                else
                    v = app.(parameterName).Value;
                end
                if ismember(parameterName,needConv)
                    app.FilterDesigner.(parameterName) = v*1e6;
                else
                    app.FilterDesigner.(parameterName) = v;
                end
                pass = false;
            catch E
                pass = true;
            end
        end
        
        function updateGUIRates(app)
            attributesToUpdate = {...
                'TxRateHB1','TxRateHB2','TxRateHB3','TxRateFIR',...
                'RxRateHB1','RxRateHB2','RxRateHB3','RxRateFIR',...
                'PLLRate','ADCRate','DACRate','PLLRate'};
            
            checks = app.FilterDesigner.validatePathRates();
                
            for attr = attributesToUpdate
                app.([attr{:}]).Value = app.FilterDesigner.(attr{:})/1e6;
                % Color rate if invalid
                if getfield(getfield(checks,attr{:}),'pass')
                    app.([attr{:}]).FontColor = app.Black;
                else
                    app.([attr{:}]).FontColor = app.Red;
                end
            end
            app.updateConfigStatus();
        end
    
        function updateGUIHBs(app)
            attributesToUpdate = {...
                'TxHB1','TxHB2','TxHB3','TxFIR',...
                'RxHB1','RxHB2','RxHB3','RxFIR',...
                'PLLDivider','DACDivider'};
            
            for attr = attributesToUpdate
                app.([attr{:}]).Value = num2str(app.FilterDesigner.(attr{:}));
            end
            app.updateConfigStatus();
        end
    
        function updateGUIFilterConfigs(app)
            attributesToUpdate = {...
                'TxApass','TxAstop',...
                'RxApass','RxAstop'};
            
            for attr = attributesToUpdate
                app.([attr{:}]).Value = (app.FilterDesigner.(attr{:}));
            end
            
            attributesToUpdate = {...
                'TxFpass','TxFstop','TxAnalogCuttoffFrequency',...
                'RxFpass','RxFstop','RxAnalogCuttoffFrequency'};
            
            for attr = attributesToUpdate
                app.([attr{:}]).Value = (app.FilterDesigner.(attr{:}))/1e6;
            end
        end
    
        
        function updateConfigStatus(app)
            % Check if configuration is valid
            validFlag = app.FilterDesigner.ValidConfiguration;
            if validFlag
                app.StatusLight.Color = app.Green;
                app.ErrorStatus.Text = '';
                app.DesignRXFilterButton.Enable = true;
                app.DesignTXFilterButton.Enable = true;
            else
                app.StatusLight.Color = app.Red;
                e = app.FilterDesigner.getConfigurationError();
                app.ErrorStatus.Text = e.msg;
                app.DesignRXFilterButton.Enable = false;
                app.DesignTXFilterButton.Enable = false;
            end
            app.updateAvailableTaps();
        end
    
        
        function updateAvailableTaps(app)
            app.AvailableFIRTaps.Value = app.FilterDesigner.AvailableFIRTaps;
        end
        
        function UpdatePlot(app,FilterStages,Axes,ConverterRate,Apass,Astop,Fpass,Fstop)
            G = 8192;
            f = linspace(0,app.FilterDesigner.DataRate/2,G);
            plot(Axes, f, mag2db(abs(freqz(FilterStages,f,ConverterRate))) );           
            hold(Axes,'on');
            line(Axes, [Fpass Fpass], [-(Apass/2) -100], 'Color', 'Red');
            line(Axes, [0 Fpass], [-(Apass/2) -(Apass/2)], 'Color', 'Red');
            line(Axes, [0 Fstop], [Apass/2 Apass/2], 'Color', 'Red');
            line(Axes, [Fstop Fstop], [Apass/2 -Astop], 'Color', 'Red');
            line(Axes, [Fstop app.FilterDesigner.DataRate], [-Astop -Astop], 'Color', 'Red');
            xlim(Axes, [0 app.FilterDesigner.DataRate/2]);
            hold(Axes,'off');
        end
        
        function SaveToFTR(app)
            [filename,path] = uiputfile('*.ftr', 'Save coefficients as');
            if filename == 0
                return;
            else
                newpath = strcat(path,filename);
            end
            
            fid = fopen(newpath,'w');
            
            fprintf(fid, '# Generated with AD9361 Filter Design Wizard %s\r\n', get_version);
            fprintf(fid, '# MATLAB %s, %s\r\n', version(), datestr(now()));
            fprintf(fid, '# Inputs:\r\n');
            
            %FIXME
            %converter_rate = get_converter_clk(handles);
            %converter_rate = get_ADC_clk(handles);
            
%             PLL_rate = str2double(get(handles.Pll_rate, 'String')) * 1e6;
%             [rx_FIR_rate, rx_HB1_rate, rx_HB2_rate, rx_HB3_rate, rx_RFbw_hw, ...
%                 tx_FIR_rate, tx_HB1_rate, tx_HB2_rate, tx_HB3_rate, tx_RFbw_hw] = get_path_rates(handles);
            
            %fprintf(fid, '# PLL CLK Frequency = %f Hz\r\n', pll_rate);
            %fprintf(fid, '# Converter Sample Frequency = %f Hz\r\n', converter_rate);
            fprintf(fid, '# Data Sample Frequency = %.0f Hz\r\n', app.FilterDesigner.DataRate);
            if app.FilterDesigner.RxPhaseEQ
                fprintf(fid, '# RX Phase equalization = %f ns\r\n', app.FilterDesigner.TxTargetDelay);
            end
            if app.FilterDesigner.RxPhaseEQ
                fprintf(fid, '# TX Phase equalization = %f ns\r\n', app.FilterDesigner.RxTargetDelay);
            end
            fprintf(fid, 'TX 3 GAIN %d INT %d\r\n', handles.tx.gain, app.FilterDesigner.TxFIR);
            fprintf(fid, 'RX 3 GAIN %d DEC %d\r\n', handles.rx.gain, app.FilterDesigner.RxFIR);
            fprintf(fid, 'RTX %.0f %.0f %.0f %.0f %.0f %.0f\r\n', app.FilterDesigner.PLLRate, app.FilterDesigner.TxRateHB3,...
                app.FilterDesigner.TxRateHB2, app.FilterDesigner.TxRateHB1, app.FilterDesigner.TxRateFIR, app.FilterDesigner.DataRate);
            fprintf(fid, 'RRX %.0f %.0f %.0f %.0f %.0f %.0f\r\n', app.FilterDesigner.PLLRate, app.FilterDesigner.RxRateHB3,...
                app.FilterDesigner.RxRateHB2, app.FilterDesigner.RxRateHB1, app.FilterDesigner.RxRateFIR, app.FilterDesigner.DataRate);
            fprintf(fid, 'BWTX %.0f\r\n', app.FilterDesigner.AnalogCuttoffFrequencyTxHW);
            fprintf(fid, 'BWRX %.0f\r\n', app.FilterDesigner.AnalogCuttoffFrequencyRxHW);
            
            % concat and transform Rx and Tx coefficient matrices for output
            coefficients = flip(rot90(vertcat(handles.tfirtaps, handles.rfirtaps)));
            
            % output all non-zero coefficients since they're padded to 128 with zeros
            for i = 1:handles.nfirtaps
                fprintf(fid, '%d,%d\r\n', coefficients(i,:));
            end
            
            fclose(fid);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Setup HB configuration            
            app.FilterDesigner = ad936xFilterDesigner();
            % Update rates on GUI based on filter designer
            app.updateGUIRates();
            % Update HBs on GUI based on filter designer
            app.updateGUIHBs();
            % Update data rate
            app.DataRate.Value = app.FilterDesigner.DataRate/1e6;
            % Update filter configs
            app.updateGUIFilterConfigs();
        end

        % Value changed function: DACDivider, DataRate, PLLDivider, 
        % RxFIR, RxHB1, RxHB2, RxHB3, TxFIR, TxHB1, TxHB2, TxHB3
        function HBValueChanged(app, event)
            attributesToUpdate = {...
                'TxHB1','TxHB2','TxHB3','TxFIR',...
                'RxHB1','RxHB2','RxHB3','RxFIR',...
                'DACDivider','PLLDivider','DataRate'};
            
            % Pass new configuration into Filter Designer for parameter validation
            for attr = attributesToUpdate
                app.updateFilterDesigner(attr{:});
            end

            % Check if configuration is valid
            app.updateConfigStatus();
            app.updateGUIRates();   

        end

        % Value changed function: TxAnalogCuttoffFrequency, TxApass, 
        % TxAstop, TxFpass, TxFstop
        function DesignConfigValueChanged(app, event)
            attributesToUpdate = {...
                'TxApass','TxAstop','TxFpass','TxFstop','TxAnalogCuttoffFrequency'...
                'RxApass','RxAstop','RxFpass','RxFstop','RxAnalogCuttoffFrequency'};
            
            % Pass new configuration into Filter Designer for parameter validation
            for attr = attributesToUpdate
                app.updateFilterDesigner(attr{:});
            end
            
            % Check if configuration is valid
            app.updateConfigStatus();
            
        end

        % Value changed function: EnTxphEQ
        function EnTxphEQValueChanged(app, event)
            value = app.EnTxphEQ.Value;
            if value
                app.TxphEQ.Editable = true;
                app.FilterDesigner.TxPhaseEQ = 1;
            else
                app.TxphEQ.Editable = false;
                app.FilterDesigner.TxPhaseEQ = 0;
            end                
        end

        % Value changed function: TxphEQ
        function TxphEQValueChanged(app, event)
            app.FilterDesigner.TxTargetDelay = app.TxphEQ.Value;
        end

        % Button pushed function: DesignTXFilterButton
        function DesignTXFilterButtonPushed(app, event)
            app.DesignTXFilterButton.BackgroundColor = app.Grey;
            pause(0.1);
            [pass,~,~,fpTaps,stats] = app.FilterDesigner.designFilter('Tx');
            if pass
                app.DesignTXFilterButton.BackgroundColor = app.Green;
            else
                app.DesignTXFilterButton.BackgroundColor = app.Red;
            end
            % Update plot
            FIR = dsp.FIRInterpolator('Numerator',fpTaps,'InterpolationFactor',app.FilterDesigner.TxFIR);
            FilterStages = createObjects(app.FilterDesigner);
            addStage(FilterStages, FIR, 1);
            UpdatePlot(app,FilterStages,app.UIAxesTX,app.FilterDesigner.DACRate,app.FilterDesigner.TxApass,...
                app.FilterDesigner.TxAstop,app.FilterDesigner.TxFpass,app.FilterDesigner.TxFstop);
            % Update design state table
            app.DesignResultsTX.Data = table(fieldnames(stats),[stats.DelayVarianceNanoSeconds,...
                stats.UsedTaps,stats.Apass,stats.Astop].');
            

        end

        % Button pushed function: AutoConfigureHBFIRButton
        function AutoConfigureHBFIRButtonPushed(app, event)
            app.FilterDesigner.DetermineBestConfiguration();
            % Set rates
            app.updateGUIRates;
            app.updateGUIHBs;
        end

        % Button pushed function: DesignRXFilterButton
        function DesignRXFilterButtonPushed(app, event)
            app.DesignRXFilterButton.BackgroundColor = app.Grey;
            pause(0.1);
            [pass,~,~,fpTaps] = app.FilterDesigner.designRX();
            if pass
                app.DesignRXFilterButton.BackgroundColor = app.Green;
            else
                app.DesignRXFilterButton.BackgroundColor = app.Red;
            end
            % Update plot
            FIR = dsp.FIRDecimator('Numerator',fpTaps,'DecimationFactor',app.FilterDesigner.RxFIR);
            [~,FilterStages] = createObjects(app.FilterDesigner);
            addStage(FilterStages, FIR);
            UpdatePlot(app,FilterStages,app.UIAxesRX,app.FilterDesigner.ADCRate,app.FilterDesigner.RxApass,...
                app.FilterDesigner.RxAstop,app.FilterDesigner.RxFpass,app.FilterDesigner.RxFstop);
        end

        % Button pushed function: ToftrFileButton, ToftrFileButton_2
        function ToftrFileButton_2Pushed(app, event)
            SaveToFTR(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ad936xFilterWizardUIFigure = uifigure;
            app.ad936xFilterWizardUIFigure.Name = 'ad936x Filter Wizard';
            app.TXPathPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.TXPathPanel.Title = 'TX Path';
            app.HB1DropDownLabel = uilabel(app.TXPathPanel);
            app.HB1DropDownLabel.Text = 'HB1';
            app.TxHB1 = uidropdown(app.TXPathPanel);
            app.TxHB1.Items = {'1x', '2x'};
            app.TxHB1.ItemsData = {'1', '2'};
            app.TxHB1.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.TxHB1.Value = '1';
            app.HB2DropDownLabel = uilabel(app.TXPathPanel);
            app.HB2DropDownLabel.Text = 'HB2';
            app.TxHB2 = uidropdown(app.TXPathPanel);
            app.TxHB2.Items = {'1x', '2x'};
            app.TxHB2.ItemsData = {'1', '2'};
            app.TxHB2.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.TxHB2.Value = '1';
            app.HB3DropDownLabel = uilabel(app.TXPathPanel);
            app.HB3DropDownLabel.Text = 'HB3';
            app.TxHB3 = uidropdown(app.TXPathPanel);
            app.TxHB3.Items = {'1x', '2x', '3x'};
            app.TxHB3.ItemsData = {'1', '2', '3'};
            app.TxHB3.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.TxHB3.Value = '1';
            app.FIRDropDownLabel = uilabel(app.TXPathPanel);
            app.FIRDropDownLabel.Text = 'FIR';
            app.TxFIR = uidropdown(app.TXPathPanel);
            app.TxFIR.Items = {'1x', '2x', '4x'};
            app.TxFIR.ItemsData = {'1', '2', '4'};
            app.TxFIR.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.TxFIR.Value = '1';
            app.TxRateFIR = uieditfield(app.TXPathPanel, 'numeric');
            app.TxRateFIR.Editable = 'off';
            app.TxRateHB1 = uieditfield(app.TXPathPanel, 'numeric');
            app.TxRateHB1.Editable = 'off';
            app.TxRateHB2 = uieditfield(app.TXPathPanel, 'numeric');
            app.TxRateHB2.Editable = 'off';
            app.TxRateHB3 = uieditfield(app.TXPathPanel, 'numeric');
            app.TxRateHB3.Editable = 'off';
            app.RXPathPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.RXPathPanel.Title = 'RX Path';
            app.HB1DropDown_2Label = uilabel(app.RXPathPanel);
            app.HB1DropDown_2Label.Text = 'HB1';
            app.RxHB1 = uidropdown(app.RXPathPanel);
            app.RxHB1.Items = {'1x', '2x'};
            app.RxHB1.ItemsData = {'1', '2'};
            app.RxHB1.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.RxHB1.Value = '1';
            app.HB2DropDown_2Label = uilabel(app.RXPathPanel);
            app.HB2DropDown_2Label.Text = 'HB2';
            app.RxHB2 = uidropdown(app.RXPathPanel);
            app.RxHB2.Items = {'1x', '2x'};
            app.RxHB2.ItemsData = {'1', '2'};
            app.RxHB2.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.RxHB2.Value = '1';
            app.HB3DropDown_2Label = uilabel(app.RXPathPanel);
            app.HB3DropDown_2Label.Text = 'HB3';
            app.RxHB3 = uidropdown(app.RXPathPanel);
            app.RxHB3.Items = {'1x', '2x', '3x'};
            app.RxHB3.ItemsData = {'1', '2', '3'};
            app.RxHB3.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.RxHB3.Value = '1';
            app.FIRDropDown_2Label = uilabel(app.RXPathPanel);
            app.FIRDropDown_2Label.Text = 'FIR';
            app.RxFIR = uidropdown(app.RXPathPanel);
            app.RxFIR.Items = {'1x', '2x', '4x'};
            app.RxFIR.ItemsData = {'1', '2', '4'};
            app.RxFIR.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.RxFIR.Value = '1';
            app.RxRateFIR = uieditfield(app.RXPathPanel, 'numeric');
            app.RxRateFIR.Editable = 'off';
            app.RxRateHB1 = uieditfield(app.RXPathPanel, 'numeric');
            app.RxRateHB1.Editable = 'off';
            app.RxRateHB2 = uieditfield(app.RXPathPanel, 'numeric');
            app.RxRateHB2.Editable = 'off';
            app.RxRateHB3 = uieditfield(app.RXPathPanel, 'numeric');
            app.RxRateHB3.Editable = 'off';
            app.ClocksPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.ClocksPanel.Title = 'Clocks';
            app.DACDividerDropDownLabel = uilabel(app.ClocksPanel);
            app.DACDividerDropDownLabel.Text = 'DAC Divider';
            app.DACDivider = uidropdown(app.ClocksPanel);
            app.DACDivider.Items = {'1x', '2x'};
            app.DACDivider.ItemsData = {'1', '2'};
            app.DACDivider.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.DACDivider.Value = '1';
            app.PLLDividerDropDownLabel = uilabel(app.ClocksPanel);
            app.PLLDividerDropDownLabel.Text = 'PLL Divider';
            app.PLLDivider = uidropdown(app.ClocksPanel);
            app.PLLDivider.Items = {'1x', '2x', '4x', '8x', '16x', '32x', '64x'};
            app.PLLDivider.ItemsData = {'1', '2', '4', '8', '16', '32', '64'};
            app.PLLDivider.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.PLLDivider.Value = '1';
            app.StatusLight = uilamp(app.ClocksPanel);
            app.ErrorStatus = uilabel(app.ClocksPanel);
            app.ErrorStatus.Text = '';
            app.MHzLabel_13 = uilabel(app.ClocksPanel);
            app.MHzLabel_13.Text = 'MHz';
            app.DACRateEditField_2Label = uilabel(app.ClocksPanel);
            app.DACRateEditField_2Label.Text = 'DAC Rate';
            app.DACRate = uieditfield(app.ClocksPanel, 'numeric');
            app.DACRate.Editable = 'off';
            app.MHzLabel_14 = uilabel(app.ClocksPanel);
            app.MHzLabel_14.Text = 'MHz';
            app.ADCRateEditFieldLabel = uilabel(app.ClocksPanel);
            app.ADCRateEditFieldLabel.Text = 'ADC Rate';
            app.ADCRate = uieditfield(app.ClocksPanel, 'numeric');
            app.ADCRate.Editable = 'off';
            app.MHzLabel_15 = uilabel(app.ClocksPanel);
            app.MHzLabel_15.Text = 'MHz';
            app.PLLRateEditFieldLabel = uilabel(app.ClocksPanel);
            app.PLLRateEditFieldLabel.Text = 'PLL Rate';
            app.PLLRate = uieditfield(app.ClocksPanel, 'numeric');
            app.PLLRate.Editable = 'off';
            app.AvailableFIRTapsLabel = uilabel(app.ClocksPanel);
            app.AvailableFIRTapsLabel.Text = 'Available FIR Taps';
            app.AvailableFIRTaps = uieditfield(app.ClocksPanel, 'numeric');
            app.AvailableFIRTaps.Editable = 'off';
            app.DeviceSettingsPanel = uipanel(app.ad936xFilterWizardUIFigure);
            app.DeviceSettingsPanel.Title = 'Device Settings';
            app.DataRateEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.DataRateEditFieldLabel.Text = 'DataRate';
            app.DataRate = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.DataRate.Limits = [0.520833 61.44];
            app.DataRate.ValueDisplayFormat = '%5.3f';
            app.DataRate.ValueChangedFcn = createCallbackFcn(app, @HBValueChanged, true);
            app.DataRate.Value = 8;
            app.DeviceDropDownLabel = uilabel(app.DeviceSettingsPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.DeviceSettingsPanel);
            app.DeviceDropDown.Items = {'AD9361', 'AD9364', 'Pluto'};
            app.DeviceDropDown.Value = 'AD9361';
            app.ProfileDropDownLabel = uilabel(app.DeviceSettingsPanel);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.DeviceSettingsPanel);
            app.ProfileDropDown.Items = {'LTE5', 'LTE10', 'LTE15', 'LTE20', 'Custom'};
            app.ProfileDropDown.Value = 'LTE5';
            app.AutoConfigureHBFIRButton = uibutton(app.DeviceSettingsPanel, 'push');
            app.AutoConfigureHBFIRButton.ButtonPushedFcn = createCallbackFcn(app, @AutoConfigureHBFIRButtonPushed, true);
            app.AutoConfigureHBFIRButton.Text = 'Auto Configure HB/FIR';
            app.MHzLabel_21 = uilabel(app.DeviceSettingsPanel);
            app.MHzLabel_21.Text = 'MHz';
            app.TabGroup = uitabgroup(app.ad936xFilterWizardUIFigure);
            app.TXFilterTab = uitab(app.TabGroup);
            app.TXFilterTab.Title = 'TX Filter';
            app.UIAxesTX = uiaxes(app.TXFilterTab);
            xlabel(app.UIAxesTX, 'Frequency (MHz)')
            ylabel(app.UIAxesTX, 'Magnitude (dB)')
            app.FilterSpecificationsPanel = uipanel(app.TXFilterTab);
            app.FilterSpecificationsPanel.Title = 'Filter Specifications';
            app.FrequencyPanel = uipanel(app.FilterSpecificationsPanel);
            app.FrequencyPanel.Title = 'Frequency';
            app.FpassEditFieldLabel = uilabel(app.FrequencyPanel);
            app.FpassEditFieldLabel.Text = 'Fpass';
            app.TxFpass = uieditfield(app.FrequencyPanel, 'numeric');
            app.TxFpass.Limits = [0 Inf];
            app.TxFpass.ValueChangedFcn = createCallbackFcn(app, @DesignConfigValueChanged, true);
            app.FstopEditFieldLabel = uilabel(app.FrequencyPanel);
            app.FstopEditFieldLabel.Text = 'Fstop';
            app.TxFstop = uieditfield(app.FrequencyPanel, 'numeric');
            app.TxFstop.Limits = [0 Inf];
            app.TxFstop.ValueChangedFcn = createCallbackFcn(app, @DesignConfigValueChanged, true);
            app.AnalogFcutoffEditFieldLabel = uilabel(app.FrequencyPanel);
            app.AnalogFcutoffEditFieldLabel.Text = 'Analog Fcutoff';
            app.TxAnalogCuttoffFrequency = uieditfield(app.FrequencyPanel, 'numeric');
            app.TxAnalogCuttoffFrequency.Limits = [0 Inf];
            app.TxAnalogCuttoffFrequency.ValueChangedFcn = createCallbackFcn(app, @DesignConfigValueChanged, true);
            app.MHzLabel = uilabel(app.FrequencyPanel);
            app.MHzLabel.Text = 'MHz';
            app.MHzLabel_3 = uilabel(app.FrequencyPanel);
            app.MHzLabel_3.Text = 'MHz';
            app.MHzLabel_2 = uilabel(app.FrequencyPanel);
            app.MHzLabel_2.Text = 'MHz';
            app.MagnitudePanel = uipanel(app.FilterSpecificationsPanel);
            app.MagnitudePanel.Title = 'Magnitude';
            app.ApassEditFieldLabel = uilabel(app.MagnitudePanel);
            app.ApassEditFieldLabel.Text = 'Apass';
            app.TxApass = uieditfield(app.MagnitudePanel, 'numeric');
            app.TxApass.Limits = [0 Inf];
            app.TxApass.ValueChangedFcn = createCallbackFcn(app, @DesignConfigValueChanged, true);
            app.dBLabel = uilabel(app.MagnitudePanel);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.MagnitudePanel);
            app.dBLabel_2.Text = 'dB';
            app.AstopEditFieldLabel = uilabel(app.MagnitudePanel);
            app.AstopEditFieldLabel.Text = 'Astop';
            app.TxAstop = uieditfield(app.MagnitudePanel, 'numeric');
            app.TxAstop.Limits = [0 Inf];
            app.TxAstop.ValueChangedFcn = createCallbackFcn(app, @DesignConfigValueChanged, true);
            app.AdvancedPanel = uipanel(app.FilterSpecificationsPanel);
            app.AdvancedPanel.Title = 'Advanced';
            app.TargetDelayEditFieldLabel = uilabel(app.AdvancedPanel);
            app.TargetDelayEditFieldLabel.Text = 'Target Delay';
            app.TxphEQ = uieditfield(app.AdvancedPanel, 'numeric');
            app.TxphEQ.Limits = [0 Inf];
            app.TxphEQ.ValueChangedFcn = createCallbackFcn(app, @TxphEQValueChanged, true);
            app.EnTxphEQ = uicheckbox(app.AdvancedPanel);
            app.EnTxphEQ.ValueChangedFcn = createCallbackFcn(app, @EnTxphEQValueChanged, true);
            app.EnTxphEQ.Text = 'Phase Equalize';
            app.nsLabel = uilabel(app.AdvancedPanel);
            app.nsLabel.Text = 'ns';
            app.DesignResultsTX = uitable(app.TXFilterTab);
            app.DesignResultsTX.ColumnName = {'Design Results'};
            app.DesignResultsTX.RowName = {};
            app.DesignTXFilterButton = uibutton(app.TXFilterTab, 'push');
            app.DesignTXFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignTXFilterButtonPushed, true);
            app.DesignTXFilterButton.Text = 'Design TX Filter';
            app.ExportDesignPanelTX = uipanel(app.TXFilterTab);
            app.ExportDesignPanelTX.Title = 'Export Design';
            app.ToWorkspaceButton_2 = uibutton(app.ExportDesignPanelTX, 'push');
            app.ToWorkspaceButton_2.Text = 'To Workspace';
            app.ToftrFileButton_2 = uibutton(app.ExportDesignPanelTX, 'push');
            app.ToftrFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @ToftrFileButton_2Pushed, true);
            app.ToftrFileButton_2.Text = 'To ftr File';
            app.ToNoOSFileButton_2 = uibutton(app.ExportDesignPanelTX, 'push');
            app.ToNoOSFileButton_2.Text = 'To No-OS File';
            app.ProgramBoardButton_2 = uibutton(app.ExportDesignPanelTX, 'push');
            app.ProgramBoardButton_2.Text = 'Program Board';
            app.IPAddressEditField_2Label = uilabel(app.ExportDesignPanelTX);
            app.IPAddressEditField_2Label.Text = 'IP Address:';
            app.IPAddressEditField_2 = uieditfield(app.ExportDesignPanelTX, 'text');
            app.IPAddressEditField_2.Value = '192.168.3.2';
            app.RXFilterTab = uitab(app.TabGroup);
            app.RXFilterTab.Title = 'RX Filter';
            app.UIAxesRX = uiaxes(app.RXFilterTab);
            xlabel(app.UIAxesRX, 'Frequency (MHz)')
            ylabel(app.UIAxesRX, 'Magnitude (dB)')
            app.FilterSpecificationsPanel_2 = uipanel(app.RXFilterTab);
            app.FilterSpecificationsPanel_2.Title = 'Filter Specifications';
            app.FrequencyPanel_2 = uipanel(app.FilterSpecificationsPanel_2);
            app.FrequencyPanel_2.Title = 'Frequency';
            app.FpassEditField_2Label = uilabel(app.FrequencyPanel_2);
            app.FpassEditField_2Label.Text = 'Fpass';
            app.RxFpass = uieditfield(app.FrequencyPanel_2, 'numeric');
            app.FstopEditField_2Label = uilabel(app.FrequencyPanel_2);
            app.FstopEditField_2Label.Text = 'Fstop';
            app.RxFstop = uieditfield(app.FrequencyPanel_2, 'numeric');
            app.AnalogFcutoffEditField_2Label = uilabel(app.FrequencyPanel_2);
            app.AnalogFcutoffEditField_2Label.Text = 'Analog Fcutoff';
            app.RxAnalogCuttoffFrequency = uieditfield(app.FrequencyPanel_2, 'numeric');
            app.MHzLabel_18 = uilabel(app.FrequencyPanel_2);
            app.MHzLabel_18.Text = 'MHz';
            app.MHzLabel_19 = uilabel(app.FrequencyPanel_2);
            app.MHzLabel_19.Text = 'MHz';
            app.MHzLabel_20 = uilabel(app.FrequencyPanel_2);
            app.MHzLabel_20.Text = 'MHz';
            app.MagnitudePanel_2 = uipanel(app.FilterSpecificationsPanel_2);
            app.MagnitudePanel_2.Title = 'Magnitude';
            app.ApassEditField_2Label = uilabel(app.MagnitudePanel_2);
            app.ApassEditField_2Label.Text = 'Apass';
            app.RxApass = uieditfield(app.MagnitudePanel_2, 'numeric');
            app.dBLabel_3 = uilabel(app.MagnitudePanel_2);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.MagnitudePanel_2);
            app.dBLabel_4.Text = 'dB';
            app.AstopEditField_2Label = uilabel(app.MagnitudePanel_2);
            app.AstopEditField_2Label.Text = 'Astop';
            app.RxAstop = uieditfield(app.MagnitudePanel_2, 'numeric');
            app.AdvancedPanel_2 = uipanel(app.FilterSpecificationsPanel_2);
            app.AdvancedPanel_2.Title = 'Advanced';
            app.TargetDelayEditField_2Label = uilabel(app.AdvancedPanel_2);
            app.TargetDelayEditField_2Label.Text = 'Target Delay';
            app.TargetDelayEditField_2 = uieditfield(app.AdvancedPanel_2, 'numeric');
            app.PhaseEqualizeCheckBox_2 = uicheckbox(app.AdvancedPanel_2);
            app.PhaseEqualizeCheckBox_2.Text = 'Phase Equalize';
            app.nsLabel_2 = uilabel(app.AdvancedPanel_2);
            app.nsLabel_2.Text = 'ns';
            app.DesignResultsRX = uitable(app.RXFilterTab);
            app.DesignResultsRX.ColumnName = {'Design Results'};
            app.DesignResultsRX.RowName = {};
            app.DesignRXFilterButton = uibutton(app.RXFilterTab, 'push');
            app.DesignRXFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignRXFilterButtonPushed, true);
            app.DesignRXFilterButton.Text = 'Design RX Filter';
            app.ExportDesignPanelRX = uipanel(app.RXFilterTab);
            app.ExportDesignPanelRX.Title = 'Export Design';
            app.ToWorkspaceButton = uibutton(app.ExportDesignPanelRX, 'push');
            app.ToWorkspaceButton.Text = 'To Workspace';
            app.ToftrFileButton = uibutton(app.ExportDesignPanelRX, 'push');
            app.ToftrFileButton.ButtonPushedFcn = createCallbackFcn(app, @ToftrFileButton_2Pushed, true);
            app.ToftrFileButton.Text = 'To ftr File';
            app.ToNoOSFileButton = uibutton(app.ExportDesignPanelRX, 'push');
            app.ToNoOSFileButton.Text = 'To No-OS File';
            app.ProgramBoardButton = uibutton(app.ExportDesignPanelRX, 'push');
            app.ProgramBoardButton.Text = 'Program Board';
            app.IPAddressEditFieldLabel = uilabel(app.ExportDesignPanelRX);
            app.IPAddressEditFieldLabel.Text = 'IP Address:';
            app.IPAddressEditField = uieditfield(app.ExportDesignPanelRX, 'text');
            app.IPAddressEditField.Value = '192.168.3.2';
            app.MHzLabel_16 = uilabel(app.ad936xFilterWizardUIFigure);
            app.MHzLabel_16.Text = 'MHz';
            app.MHzLabel_17 = uilabel(app.ad936xFilterWizardUIFigure);
            app.MHzLabel_17.Text = 'MHz';
            app.Button = uibutton(app.ad936xFilterWizardUIFigure, 'push');
            app.Button.Icon = 'Analog_Devices_Logo.png';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DesignerGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ad936xFilterWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ad936xFilterWizardUIFigure)
        end
    end
end